classdef resultView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        MVPALogo                        matlab.ui.control.Image
        versionLabel                    matlab.ui.control.Label
        MVPAlabToolboxLabel             matlab.ui.control.Label
        resultFilePanel                 matlab.ui.container.Panel
        loadResultButton                matlab.ui.control.Button
        ResultsfileEditFieldLabel       matlab.ui.control.Label
        loadResultField                 matlab.ui.control.EditField
        plotResutsButton                matlab.ui.control.Button
        ChangecolorschemeDropDownLabel  matlab.ui.control.Label
        colorSchemeSelector             matlab.ui.control.DropDown
        StatisticsPanel                 matlab.ui.container.Panel
        HighlightsignificantclustersCheckBox  matlab.ui.control.CheckBox
        TabGroup                        matlab.ui.container.TabGroup
        MVPATab                         matlab.ui.container.Tab
        PerformancemetricDropDownLabel  matlab.ui.control.Label
        PerformancemetricDropDown       matlab.ui.control.DropDown
        PlottemporalgeneralizationmatrixCheckBox  matlab.ui.control.CheckBox
        MVCCTab                         matlab.ui.container.Tab
        SlidingfilterTab                matlab.ui.container.Tab
    end

    
    properties (Access = private)
        cfg
    end
    
    methods (Access = private)
        function getFocus(app)
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end
        
        function updateGUI(app)
            
            % 
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            
        end

        % Button pushed function: plotResutsButton
        function plotResutsButtonPushed(app, event)
            mvpalab_plottg(app.graph,app.cfg,app.result.cr,app.stats);
        end

        % Value changed function: colorSchemeSelector
        function colorSchemeSelectorValueChanged(app, event)
            app.graph.colorMap = getfield(app.graph.grads,...
                app.colorSchemeSelector.Value);
            colormap(app.graph.colorMap);
        end

        % Button pushed function: loadResultButton
        function loadResultButtonButtonPushed(app, event)
            % Disable load and plot button:
            app.loadResultButton.Text = 'Loading data...';
            app.loadResultButton.Enable = 'off';
            app.plotResutsButton.Enable = 'off';
            
            % File selector:        
            openDir = fullfile(getenv('HOME'),'Desktop');
            [file,path] = uigetfile([openDir '/cfg.mat'], ...
                'Select a cfg.mat file');
            app.getFocus();
            
            if file
                if strcmp(file,'cfg.mat')
                    % Load study file:
                    temp = load([path file]);
                    
                    % Update cfg file:
                    app.cfg = temp.cfg;
                    assignin('base','cfg',app.cfg);
                     
                    % Update text field, button and disable load button:
                    app.loadResultField.Value = [path file];
                    
                    
                    % Lo que quiero hacer básicamente es leer todos los
                    % resultados en el workspace base, donde ya se ha
                    % asignado también la variable CFG. Además, debemos
                    % asignar tambien el plotinit. Esta interfaz será para
                    % seleccionar los datos a plotear, y los parámetros que
                    % se pueden ajustar en el graph. Luego llamamos a un
                    % plot controller como con los análisis anteriores.
                    
                    % Load data in the base workspace:
                    % evalin('base', 'run load');
                    
                    temp = load([path filesep 'cfg.mat']);
                    app.cfg = temp.cfg;
                    temp = load([path filesep 'results' filesep 'result.mat']);
                    app.result = temp.result;
                    temp = load([path filesep 'stats' filesep 'auc' filesep 'stats.mat']);
                    app.stats = temp.stats;
  
                else
                    % Show warning and disable load button
                    warndlg(['Please select a valid cfg.mat file.'],'Warning');
                end
            else
                % Clear dir:
                app.loadResultField.Value = '';
            end
            
            % Enable load button
            app.loadResultButton.Text = 'Select file';
            app.loadResultButton.Enable = 'on';
            app.plotResutsButton.Enable = 'on';
            
            % Init graph structure:
            app.graph = mvpalab_plotinit();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.MVPALogo = uiimage(app.Panel);
            app.MVPALogo.ImageSource = 'mvpalab.png';
            app.versionLabel = uilabel(app.Panel);
            app.versionLabel.Text = 'BETA v0.1';
            app.MVPAlabToolboxLabel = uilabel(app.Panel);
            app.MVPAlabToolboxLabel.Text = 'MVPAlab Toolbox';
            app.resultFilePanel = uipanel(app.UIFigure);
            app.resultFilePanel.Title = 'Result file location:';
            app.loadResultButton = uibutton(app.resultFilePanel, 'push');
            app.loadResultButton.ButtonPushedFcn = createCallbackFcn(app, @loadResultButtonButtonPushed, true);
            app.loadResultButton.Text = 'Select file';
            app.ResultsfileEditFieldLabel = uilabel(app.resultFilePanel);
            app.ResultsfileEditFieldLabel.Text = 'Results file:';
            app.loadResultField = uieditfield(app.resultFilePanel, 'text');
            app.loadResultField.Editable = 'off';
            app.plotResutsButton = uibutton(app.UIFigure, 'push');
            app.plotResutsButton.ButtonPushedFcn = createCallbackFcn(app, @plotResutsButtonPushed, true);
            app.plotResutsButton.Text = 'Plot results';
            app.ChangecolorschemeDropDownLabel = uilabel(app.UIFigure);
            app.ChangecolorschemeDropDownLabel.Text = 'Change color scheme';
            app.colorSchemeSelector = uidropdown(app.UIFigure);
            app.colorSchemeSelector.Items = {'MVPAlab', 'Sun', 'Mercury', 'Venus', 'Earth', 'Mars', 'Jupiter', 'Saturn', 'Uranus', 'Neptune'};
            app.colorSchemeSelector.ItemsData = {'mvpalab', 'sun', 'mercury', 'venus', 'earth', 'mars', 'jupiter', 'saturn', 'uranus', 'neptune', ''};
            app.colorSchemeSelector.ValueChangedFcn = createCallbackFcn(app, @colorSchemeSelectorValueChanged, true);
            app.colorSchemeSelector.Value = 'mvpalab';
            app.StatisticsPanel = uipanel(app.UIFigure);
            app.StatisticsPanel.Title = 'Statistics:';
            app.HighlightsignificantclustersCheckBox = uicheckbox(app.StatisticsPanel);
            app.HighlightsignificantclustersCheckBox.Text = 'Highlight significant clusters';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MVPATab = uitab(app.TabGroup);
            app.MVPATab.Title = 'MVPA';
            app.PerformancemetricDropDownLabel = uilabel(app.MVPATab);
            app.PerformancemetricDropDownLabel.Text = 'Performance metric:';
            app.PerformancemetricDropDown = uidropdown(app.MVPATab);
            app.PerformancemetricDropDown.Items = {'Mean accuracy (ACC)', 'Area under the courve (AUC)'};
            app.PerformancemetricDropDown.Value = 'Mean accuracy (ACC)';
            app.PlottemporalgeneralizationmatrixCheckBox = uicheckbox(app.MVPATab);
            app.PlottemporalgeneralizationmatrixCheckBox.Text = 'Plot temporal generalization matrix';
            app.MVCCTab = uitab(app.TabGroup);
            app.MVCCTab.Title = 'MVCC';
            app.SlidingfilterTab = uitab(app.TabGroup);
            app.SlidingfilterTab.Title = 'Sliding filter';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = resultView

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end